classdef PneumoniaApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UITable                       matlab.ui.control.Table
        RunImageAnalysisButton        matlab.ui.control.Button
        SelectedFolderEditField       matlab.ui.control.EditField
        SelectedFolderEditFieldLabel  matlab.ui.control.Label
        UploadImageFolderButton       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SelectedFolderEditField.FontColor = [1 0 0];
            app.SelectedFolderEditField.Value = 'No Folder Selected';
            global F
            F = 0;
        end

        % Value changed function: SelectedFolderEditField
        function SelectedFolderEditFieldValueChanged(app, event)
            value = app.SelectedFolderEditField.Value;
        end

        % Button pushed function: UploadImageFolderButton
        function UploadImageFolderButtonPushed(app, event)
            path = uigetdir();
            global F, global files, global n_files, global d1
            F = 1;
            app.SelectedFolderEditField.Value = '';
            app.SelectedFolderEditField.FontColor = [0 0 0];
            app.SelectedFolderEditField.Value = path;
            d1 = path;
            files = dir(fullfile(d1));
            files([files.isdir]) = [];
            n_files = length(files);
        end

        % Button pushed function: RunImageAnalysisButton
        function RunImageAnalysisButtonPushed(app, event)
            global F, global files, global n_files, global d1

            if F == 0
                error('FOLDER NOT SELECTED: Please select image folder using "Upload Image Folder" Button')
            end

            FILENAMES = strings(n_files,1);
            LungSize = zeros(n_files,1);
            AveVals = zeros(n_files,1);

            for i = 1:n_files
                 filename = fullfile(d1, files(i).name);
                 [~,name,~] = fileparts(filename);
                 FILENAMES(i) = name;
            %     Read file and gray scale
                img = dicomread(filename);
                img = im2gray(img);
                img = imadjust(img);
            %     Invert image 
                testimg = 255 - img;
                testimg_clear = imclearborder(testimg);
                testimg_clear_inv = 255-testimg_clear;
            %     Find lungs
                I = testimg_clear_inv;
                [BW, thresh] = edge(I,'sobel');
                fudgeFactor = 0.8;
                Iedge = edge(I,'sobel',fudgeFactor*thresh);
            %     Dilate, fill holes, and erode
                se90 = strel('line',6,90);
                se0 = strel('line',3,0);
                Idilate = imdilate(Iedge,[se90, se0]);
                Ifill = imfill(Idilate, 'holes');
                seD = strel('diamond', 3);
                Ierode1 = imerode(Ifill,seD);
                Ierode2 = imerode(Ierode1,seD);
                Ierode3 = imerode(Ierode2,seD);
                Ifinal = imerode(Ierode3,seD);
            %     Find variables
                Ave_val = mean(img(Ifinal));
                num_pixels = sum(Ifinal(:));

                LungSize(i) = num_pixels;
                AveVals(i) = Ave_val;
            end
            addpath('Model_Functions\');
            PREDICTIONS = runmodel(LungSize, AveVals);
            Model_Predictions = table(FILENAMES, LungSize, AveVals, PREDICTIONS);
            app.UITable.Data = Model_Predictions;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadImageFolderButton = uibutton(app.UIFigure, 'push');
            app.UploadImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageFolderButtonPushed, true);
            app.UploadImageFolderButton.Text = 'Upload Image Folder';
            app.SelectedFolderEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedFolderEditFieldLabel.Text = 'Selected Folder:';
            app.SelectedFolderEditField = uieditfield(app.UIFigure, 'text');
            app.SelectedFolderEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedFolderEditFieldValueChanged, true);
            app.RunImageAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunImageAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunImageAnalysisButtonPushed, true);
            app.RunImageAnalysisButton.Text = 'Run Image Analysis';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Image File Name'; 'Lung Size (Pixels)'; 'Average Intensity'; 'Prediction'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PneumoniaApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end